classdef BasinsOfAttraction_Vilhelmiina_R_Harjoitustyo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        x_minx_maxy_miny_maxLabel      matlab.ui.control.Label
        DescendingorderLabel           matlab.ui.control.Label
        InputyourvaluesLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Separatefournumbersbyspaceseg1111Label  matlab.ui.control.Label
        Separatefournumbersbyspaceseg1201Label  matlab.ui.control.Label
        DivergenceboundEditField       matlab.ui.control.NumericEditField
        DivergenceboundEditFieldLabel  matlab.ui.control.Label
        AxisboundsEditField            matlab.ui.control.EditField
        AxisboundsEditFieldLabel       matlab.ui.control.Label
        GridpointsEditField            matlab.ui.control.NumericEditField
        GridpointsEditFieldLabel       matlab.ui.control.Label
        ToleranceEditField             matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel        matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        CoefficientsEditField          matlab.ui.control.EditField
        CoefficientsasvectorEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Initial coefficients
        Coeffs(1, 4) double = [0 0 0 0]; 
        % Initial tolerance for checking convergence
        Tol(1, 1) double {mustBePositive} = 1e-1; 
        %Amount of grid points for image clarity
        GridPoints(1, 1) double {mustBePositive} = 100; 
        %Bounds for image axis
        AxisBounds(1, 4) double = [ -1.5 1.5 -1.5 1.5 ]; 
        % Bound for checking if iteration diverges
        DivBound (1, 1) double {mustBePositive} = 1000 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %Calculating roots according to user inputs using Newton's
            %method
            np = length(app.Coeffs) - 1;
            dp = polyder(app.Coeffs);
            r = roots(app.Coeffs);
            x = linspace(-1.2, 1.2, app.GridPoints);
            y = linspace(-1.2, 1.2, app.GridPoints);
            C = zeros(length(y), length(x), 'uint8');
            cmap = [1 1 1; 1 0 0; 0 1 0; 0 0 1; 0 0 0];
            [X, Y] = meshgrid(x, y);
            Z = X + 1i*Y;
            maxiter = 100;
            tic
            % Try-catch indent to catch indexing errors with certain
            % polynomials of degree 2 or lower.
            try
            for j = 1:maxiter
                % To avoid division by zero
                Z = Z - polyval(app.Coeffs, Z)./(polyval(dp, Z) + eps); 
                for k = 1:np
                    C(abs(Z - r(k)) < app.Tol) = k;
                end
                C(abs(Z) > app.DivBound) = np + 1;
            end
            catch 
                 uialert(app.UIFigure, 'Invalid polynomial!', 'Error'); 
                 set(app.PlotButton, 'Enable', 'off');
            end
            toc

            % Drawing colormap and root divergences
            fig1 = figure();
            % Line below removes the default figure pop-up during live plot
            set(fig1, 'Visible', 'off'); 
            image(app.UIAxes, x, y, C);
            colormap(app.UIAxes, cmap);
            hold(app.UIAxes,"on")
            axis(app.UIAxes, app.AxisBounds)
            plot(app.UIAxes, r, 'o', 'MarkerFaceColor', 'k')
            hold off
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            value = app.ToleranceEditField.Value;
            if value <= 0
                uialert(app.UIFigure, 'Must be non-negative and nonzero!', 'Error');
                set(app.PlotButton, 'Enable', 'off');
            else
                app.Tol = value;
                set(app.PlotButton, 'Enable', 'on');
            end
            
        end

        % Value changed function: GridpointsEditField
        function GridpointsEditFieldValueChanged(app, event)
            value = app.GridpointsEditField.Value;
            if value <= 0
                uialert(app.UIFigure, 'Must be non-negative and nonzero!', 'Error');
                set(app.PlotButton, 'Enable', 'off');
            else
                app.GridPoints = value;
                set(app.PlotButton, 'Enable', 'on');
            end
            
        end

        % Value changed function: AxisboundsEditField
        function AxisboundsEditFieldValueChanged(app, event)
            %Convert input to an array
            values = str2num(app.AxisboundsEditField.Value);
            %Erroneous array is empty or doesn't contain 4 cells
            %On non-numerical inputs str2num returns an empty array
            if length(values) ~= 4
                uialert(app.UIFigure, 'Erroneous axis bound input!', 'Error');
                set(app.PlotButton, 'Enable', 'off');
            else
                %Check that user input axis bounds are logical
                if values(1) >= values(2)
                    uialert(app.UIFigure, 'Xmin must be less than Xmax!', 'Error');
                    set(app.PlotButton, 'Enable', 'off');
                elseif values(3) >= values(4)
                    uialert(app.UIFigure, 'Ymin must be less than Ymax!', 'Error');
                    set(app.PlotButton, 'Enable', 'off');
                else
                    app.AxisBounds = values;
                    set(app.PlotButton, 'Enable', 'on');
                end
            end
            
        end

        % Value changed function: DivergenceboundEditField
        function DivergenceboundEditFieldValueChanged(app, event)
            value = app.DivergenceboundEditField.Value;
            if value <= 0
                uialert(app.UIFigure, 'Must be non-negative and nonzero!', 'Error');
                set(app.PlotButton, 'Enable', 'off');
            else
                app.DivBound = value;
                set(app.PlotButton, 'Enable', 'on');
            end
            
        end

        % Value changed function: CoefficientsEditField
        function CoefficientsEditFieldValueChanged(app, event)
            values = str2num(app.CoefficientsEditField.Value);
            %Erroneous array is empty or doesn't contain 4 cells
            %On non-numerical inputs str2num returns an empty array
            if length(values) ~= 4
                uialert(app.UIFigure, 'Erroneous coefficient input!', 'Error');
                set(app.PlotButton, 'Enable', 'off');
            else
                app.Coeffs = values;
                set(app.PlotButton, 'Enable', 'on')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Newton''s basins of attraction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CoefficientsasvectorEditFieldLabel = uilabel(app.UIFigure);
            app.CoefficientsasvectorEditFieldLabel.Text = 'Coefficients as vector';
            app.CoefficientsEditField = uieditfield(app.UIFigure, 'text');
            app.CoefficientsEditField.ValueChangedFcn = createCallbackFcn(app, @CoefficientsEditFieldValueChanged, true);
            app.CoefficientsEditField.Value = '0 0 0 0';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.ToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 0.1;
            app.GridpointsEditFieldLabel = uilabel(app.UIFigure);
            app.GridpointsEditFieldLabel.Text = 'Grid points';
            app.GridpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridpointsEditField.ValueChangedFcn = createCallbackFcn(app, @GridpointsEditFieldValueChanged, true);
            app.GridpointsEditField.Value = 100;
            app.AxisboundsEditFieldLabel = uilabel(app.UIFigure);
            app.AxisboundsEditFieldLabel.Text = 'Axis bounds';
            app.AxisboundsEditField = uieditfield(app.UIFigure, 'text');
            app.AxisboundsEditField.ValueChangedFcn = createCallbackFcn(app, @AxisboundsEditFieldValueChanged, true);
            app.AxisboundsEditField.Value = '-1.5 1.5 -1.5 1.5';
            app.DivergenceboundEditFieldLabel = uilabel(app.UIFigure);
            app.DivergenceboundEditFieldLabel.Text = 'Divergence bound';
            app.DivergenceboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.DivergenceboundEditField.ValueChangedFcn = createCallbackFcn(app, @DivergenceboundEditFieldValueChanged, true);
            app.DivergenceboundEditField.Value = 1000;
            app.Separatefournumbersbyspaceseg1201Label = uilabel(app.UIFigure);
            app.Separatefournumbersbyspaceseg1201Label.Text = '(Separate four numbers by spaces eg. 1 2 0 -1)';
            app.Separatefournumbersbyspaceseg1111Label = uilabel(app.UIFigure);
            app.Separatefournumbersbyspaceseg1111Label.Text = '(Separate four numbers by spaces eg. -1 1 -1 1)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'jippii.jpg');
            app.InputyourvaluesLabel = uilabel(app.UIFigure);
            app.InputyourvaluesLabel.Text = 'Input your values:';
            app.DescendingorderLabel = uilabel(app.UIFigure);
            app.DescendingorderLabel.Text = '(Descending order)';
            app.x_minx_maxy_miny_maxLabel = uilabel(app.UIFigure);
            app.x_minx_maxy_miny_maxLabel.Text = '(x_min, x_max, y_min, y_max)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasinsOfAttraction_Vilhelmiina_R_Harjoitustyo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end